classdef two_tank_lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Servo3Switch                   matlab.ui.control.ToggleSwitch
        SERVO3SwitchLabel              matlab.ui.control.Label
        Servo2Switch                   matlab.ui.control.ToggleSwitch
        SERVO2SwitchLabel              matlab.ui.control.Label
        Servo1Switch                   matlab.ui.control.ToggleSwitch
        SERVO1SwitchLabel              matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Paneldecontrol_2               matlab.ui.container.Panel
        GridLayout_2                   matlab.ui.container.GridLayout
        sv3Knob                        matlab.ui.control.Knob
        SV2Label_2                     matlab.ui.control.Label
        sv2Knob                        matlab.ui.control.Knob
        SV2Label                       matlab.ui.control.Label
        sv1Knob                        matlab.ui.control.Knob
        SV1Label                       matlab.ui.control.Label
        DMotorSwitch                   matlab.ui.control.ToggleSwitch
        MOTORDISCOSwitchLabel          matlab.ui.control.Label
        BMotorSwitch                   matlab.ui.control.ToggleSwitch
        MOTORBANDASwitchLabel          matlab.ui.control.Label
        bndKnob                        matlab.ui.control.Knob
        dskKnob                        matlab.ui.control.Knob
        MTBSwitch                      matlab.ui.control.ToggleSwitch
        MOTOBOMBALabel                 matlab.ui.control.Label
        Ret3Switch                     matlab.ui.control.ToggleSwitch
        RETENEDOR3Label                matlab.ui.control.Label
        Ret2Switch                     matlab.ui.control.ToggleSwitch
        RETENEDOR2Label                matlab.ui.control.Label
        Ret1Switch                     matlab.ui.control.ToggleSwitch
        RETENEDOR1Label                matlab.ui.control.Label
        Electro2Switch                 matlab.ui.control.ToggleSwitch
        ELECTROVLVULA2Label            matlab.ui.control.Label
        Electro1Switch                 matlab.ui.control.ToggleSwitch
        ELECTROVLVULA1Label            matlab.ui.control.Label
        EmergSwitch                    matlab.ui.control.ToggleSwitch
        EMERGENCIASwitchLabel          matlab.ui.control.Label
        StopSwitch                     matlab.ui.control.ToggleSwitch
        DETENERSwitchLabel             matlab.ui.control.Label
        StartSwitch                    matlab.ui.control.ToggleSwitch
        INICIARSwitchLabel             matlab.ui.control.Label
        Messages                       matlab.ui.control.TextArea
        MensajesTextAreaLabel          matlab.ui.control.Label
        Paneldeconexin                 matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        DownloadButton                 matlab.ui.control.Button
        SendFileButton                 matlab.ui.control.Button
        UploadButton                   matlab.ui.control.Button
        StartPLCButton                 matlab.ui.control.StateButton
        ConnectRPi                     matlab.ui.control.StateButton
        ActuadoresbandadeprocesoPanel  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        RET1                           matlab.ui.control.Lamp
        Retenedor1Label                matlab.ui.control.Label
        RET2                           matlab.ui.control.Lamp
        Retenedor2Label                matlab.ui.control.Label
        RET3                           matlab.ui.control.Lamp
        BND                            matlab.ui.control.Lamp
        MotorbandaLabel                matlab.ui.control.Label
        DSK                            matlab.ui.control.Lamp
        MotordiscoLabel                matlab.ui.control.Label
        Retenedor3Label                matlab.ui.control.Label
        SensoresPanel                  matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Tank1Level                     matlab.ui.control.LinearGauge
        Tank2Level                     matlab.ui.control.LinearGauge
        IR3                            matlab.ui.control.Lamp
        Retenedor3Label_4              matlab.ui.control.Label
        IR2                            matlab.ui.control.Lamp
        Retenedor3Label_3              matlab.ui.control.Label
        IR1                            matlab.ui.control.Lamp
        Retenedor3Label_2              matlab.ui.control.Label
        US2                            matlab.ui.control.NumericEditField
        Tanque2cmLabel                 matlab.ui.control.Label
        US1                            matlab.ui.control.NumericEditField
        Tanque1cmLabel                 matlab.ui.control.Label
        ActuadorescontroldenivelPanel  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        SV1                            matlab.ui.control.Lamp
        Servovlvula1Label              matlab.ui.control.Label
        SV2                            matlab.ui.control.Lamp
        Servovlvula2Label              matlab.ui.control.Label
        SV3                            matlab.ui.control.Lamp
        Servovlvula3LampLabel          matlab.ui.control.Label
        MTB                            matlab.ui.control.Lamp
        MotobombaLabel                 matlab.ui.control.Label
        EV2                            matlab.ui.control.Lamp
        Electrovlvula2LampLabel        matlab.ui.control.Label
        EV1                            matlab.ui.control.Lamp
        Electrovlvula1LampLabel        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        raspiIP = '10.20.40.58'; % ip address of raspberry pi board
        user % username
        myraspi % raspberry pi object
        modbusMaster % modbus server object
        modbusPort = 152
        statusTimer % timer send user connection status
        monitorTimer % timer to read system variables

        % Control panel
        StartReg = 1025 % register for START button
        StopReg = 1026 % register for STOP button
        EmergReg = 1027 % register for EMERGENCY button

        % Communication
        serialPort3 = 'COM5' % serial port to notify raspberry pi availability
        serialDevice3 = '' % serial port object
        tcpPort1 = 31000 % tcp port for sending client's commands (PCL program)
        tcpClient1 % tcp client 1
        tcpPort2 = 5555 % tcp port for monitoring system variables
        tcpClient2 % tcp client 2

        % Flags
        RPiAvailable = '0'
        RPiConnection = '0' % '1' if raspberry pi connection has been established
        uploadedFile % name of uploaded file
        uploadedPath = '' % path of uploaded file
        PLCRunning = '0' % '1' if PLC is running
        FileSent = '0' % '1' if file has been sent to the Raspberry Pi

        % Plots and GUI
        dataSize = 1 % number of readings to be made before updating GUI
        PlotLine % line to plot tank 1 liquid level
        PlotLine2 % line to plot  tank 2 liquid level
        Tank1Data % variable to store tank 1 liquid levels
        Tank2Data % variable to store tank 2 liquid levels
        selectedVar = 'LED 1'
        xlen

        % Data storage
        data_array = zeros(9, 100000) % array for storing system variables
        count = 1 % number of readings made
    end
    methods (Access = private)
        %% ==== Callbacks ==== %% 
        function updateRPiStatus(app, src, ~)
            % Enable or disable Raspberry Pi connection button when its
            % availability changes.

            if src.NumBytesAvailable>0
                msg = read(src, src.NumBytesAvailable, 'char');
                msg = split(msg, '|');
                app.RPiAvailable = msg{1};
                if strcmp(app.RPiAvailable, '1')&&(~strcmp(msg{2}, app.user)) % user is connected to raspberry pi
                    app.ConnectRPi.Enable = 'off';
                else % user has disconnected from raspberry pi
                    app.ConnectRPi.Enable = 'on';
                end
            end
        end
        %% ==== Timer functions ==== %%
        function TimerFcn(app, ~, ~)
            % Send user connection confirmation through tcp port

            writeline(app.tcpClient1,  ['connected', '|', '-']);
        end

        function readSystemVariables(app, ~, ~)
            % Read the state of actuators and sensors

            try
                % Read digital inputs and update indicators
                dInputs = read(app.modbusMaster, 'inputs', 804, 3);
                app.turnOnOffLED(app.IR1, dInputs(1), 'green');
                app.turnOnOffLED(app.IR2, dInputs(2), 'green');
                app.turnOnOffLED(app.IR3, dInputs(3), 'green');
            catch me
                app.Messages.Value = me.message;
            end
            try
                % Read digital outputs and update indicators
                dOutputs = read(app.modbusMaster, 'coils', 804, 3);
                app.turnOnOffLED(app.MTB, dOutputs(1), 'green');
                app.turnOnOffLED(app.EV1, dOutputs(2), 'green');
                app.turnOnOffLED(app.EV2, dOutputs(3), 'green');
                app.Messages.Value = strcat(num2str(dOutputs(1)), num2str(dOutputs(2)), num2str(dOutputs(3)));
            catch me
                app.Messages.Value = me.message;
            end
            try
                % Read analog inputs and update indicators
                aInputs = read(app.modbusMaster, 'inputregs', 101, 2)/1000;
                app.US1.Value = aInputs(1);
                app.Tank1Level.Value = aInputs(1);
                app.US2.Value = aInputs(2);
                app.Tank2Level.Value = aInputs(2);
                app.PlotLine.YData = app.updateLinePlot(app.PlotLine.YData, aInputs(1));
                app.PlotLine2.YData = app.updateLinePlot(app.PlotLine2.YData, aInputs(2));
            catch me
                app.Messages.Value = me.message;
            end
            try
                % Read analog outputs and update indicators
                aOutputs = read(app.modbusMaster, 'holdingregs', 101, 8);
                app.Messages.Value = '';
            catch me
                app.Messages.Value = me.message;
            end
            
        end
        %% Helper functions 
        function turnOnOffLED(~, led, state, color)
            if state==1
                led.Color = color;
            else
                led.Color = 'black';
            end
        end
        function updateControlLEDs(app, colors)
            %app.StartButton.BackgroundColor = colors{1};
            %app.StopButton.BackgroundColor = colors{2};
            %app.EmergButton.BackgroundColor = colors{3};
        end
        function lineplot = updateLinePlot(app, lineplot, data)
              lineplot = circshift(lineplot, app.dataSize);
              lineplot(1:app.dataSize) = data;
        end
        function enableControlButtons(app, state)
            % Enable buttons
            app.StartButton.Enable = state;
            app.StopButton.Enable = state;
            app.EmergButton.Enable = state;
            % Enable switches
            app.StartSwitch.Enable = state;
            app.StopSwitch.Enable = state;
            app.EmergSwitch.Enable = state;
        end

        function stopActuators(app)
            % Turn off the system's actuators

            sv1KnobValue = 0;
            sv2KnobValue = 0;
            sv3KnobValue = 0;

            ev1SwitchValue = 0;
            ev2SwitchValue = 0;

            mtbSwitchValue = 0;

            ret1SwitchValue = 0;
            ret2SwitchValue = 0;
            ret3SwitchValue = 0;

            dskSwitchValue = 0;
            bndSwitchValue = 0;

            dskKnobValue = 0;
            bndKnobValue = 0;

            write(app.modbusMaster, 'holdingregs', 1028, ...
                [sv1KnobValue, sv2KnobValue, sv3KnobValue, ...
                ev1SwitchValue, ev2SwitchValue, ...
                mtbSwitchValue, ...
                ret1SwitchValue, ret2SwitchValue, ret3SwitchValue, ...
                dskSwitchValue, dskKnobValue, ...
                bndSwitchValue, bndKnobValue], ...
                'uint16');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Messages.Value = 'Iniciando...';

            %==== Get user information ====%
            try
                log = compiler.UserInfo();
                app.user = log.UserID;
                app.UserInfo.Text = strcat('Usuario:', {' '}, app.user);
            catch me
                app.Messages.Value = me.message;
            end

            %==== Initialize plots ====%
            disableDefaultInteractivity(app.UIAxes);
            app.UIAxes.Toolbar.Visible = 'off';
            % Configure x- and y- axis
            app.xlen = 500;
            app.Tank1Data = zeros(1, app.xlen+1);
            app.Tank2Data = zeros(1, app.xlen+1);
            app.UIAxes.XLim = [0 app.xlen];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YLim = [0 30];
            % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,0:app.xlen, zeros(1, app.xlen+1));
            app.PlotLine2 = plot(app.UIAxes, 0:app.xlen, zeros(1, app.xlen+1));
            legend(app.UIAxes, 'Tanque 1', 'Tanque 2');

            %==== Timers ====%
            % Create timer that sends user connection status at regular intervals
            app.statusTimer = timer( ...
                "ExecutionMode", 'fixedRate', ...
                'Period', 60, ...
                'BusyMode', 'queue', ...
                'StartDelay', 0.1, ...
                'TimerFcn', @app.TimerFcn);
            stop(app.statusTimer); % stops
            % Create timer that reads system variables at regular intervals
            app.monitorTimer = timer( ...
                "ExecutionMode", "fixedRate", ...
                'Period', 0.1, ...
                'BusyMode', 'drop', ...
                'StartDelay', 1, ...
                'TimerFcn', @app.readSystemVariables);
            stop(app.monitorTimer);

            %==== Serial ports ====%
            try 
                app.serialDevice3 = serialport('COM5', 921600);
                configureTerminator(app.serialDevice3, 'LF');
                configureCallback(app.serialDevice3, 'byte', 1, @app.updateRPiStatus);
            catch me
                app.Messages.Value = me.message;
                return;
            end
            %==== Check Raspberry Pi availability ====%
            % Create tcp client
            try
                client = tcpclient('localhost', 30000, 'Timeout', 15);
                % Send a message and receive a response that informs about
                % the raspberry pi status
                app.RPiAvailable = writeread(client, ['check','|', '']);
                if strcmp(app.RPiAvailable, '0') % RPi is available 
                    app.ConnectRPi.Enable = 'on';
                end
            catch
                app.Messages.Value = ['No se pudo iniciar comunicación TCP.' ...
                    'Verifique que el script de MATLAB se esté ejecuntando'];
                return;
            end
            pause(2.5);
            app.Messages.Value = 'Inicialización éxitosa';
        end

        % Value changed function: ConnectRPi
        function ConnectRPiValueChanged(app, event)
            value = app.ConnectRPi.Value;

            %==== Check if PLC is running ====%
            if strcmp(app.PLCRunning, '1')
                app.Messages.Value = ['El programa PLC está ejecutandose.' ...
                    'Detenga el programa antes de intentar conectarse a' ...
                    'las Raspberry Pi'];
                app.ConnectRPi.Value = ~value;
                return
            end

            %==== Create tcp client ====%
            try
                app.Messages.Value = 'Iniciando comunicación TCP...';
                client = tcpclient('localhost', 30000, 'Timeout', 30);
                pause(0.5);
            catch
                app.Messages.Value = 'No se pudo iniciar comunicación TCP';
                app.ConnectRPi.Value = ~value;
                return;
            end

            %==== Connect to raspberry pi ====%
            if value
                % Raspberry Pi is available
                if strcmp(app.RPiAvailable, '0')
                    try 
                        app.Messages.Value = 'Conectando a Raspberry Pi';
                        app.RPiConnection = writeread(client, ['connect', '|', app.user]);
                        pause(0.5);
                    catch
                        app.Messages.Value = 'No se pudo enviar comando para conectar Raspberry Pi';
                        app.ConnectRPi.Value = ~value;
                        return;
                    end
                    % User connected to Raspberry Pi successfully
                    if strcmp(app.RPiConnection, '1')
                        app.ConnectRPi.BackgroundColor = [0.95,0.25,0.25];
                        app.ConnectRPi.Text = 'Desconectar RPi';
                        app.tcpClient2 = tcpclient('localhost', app.tcpPort2, 'Timeout', 30);
                        app.tcpClient1 = tcpclient('localhost', app.tcpPort1, 'Timeout', 30);
                        configureTerminator(app.tcpClient1, 'LF');
                        start(app.statusTimer); % Start timer to send user connection status
                        app.Messages.Value = 'Conectado';
                    else
                        app.Messages.Value = 'No se pudo conectar a la Raspberry Pi';
                        app.ConnectRPi.Value = ~value;
                        return;
                    end
                % Raspberry Pi is not available
                else
                    app.Messages.Value = 'Raspberry Pi no está disponible. Intente más tarde.';
                    app.ConnectRPi.Value = ~value;
                end
            %==== Disconnect from Raspberry Pi ====%
            else
                try
                    app.Messages.Value = 'Desconectando...';
                    app.RPiConnection = writeread(client, ['disconnect', '|', app.user]);
                    pause(0.5);
                catch
                    app.Messages.Value = 'No se pudo enviar comando de desconexión';
                    return;
                end
                app.myraspi = []; % delete raspberry pi object
                app.tcpClient1 = []; % delete tcpclient 1
                app.tcpClient2 = []; % delete tcpclient 2
                app.ConnectRPi.BackgroundColor = [0.39,0.83,0.07]; % update connection button
                app.ConnectRPi.Text = 'Conectar RPi';
                app.StartPLCButton.Enable = 'off'; % disable start button
                stop(app.statusTimer); % stop timer
                app.Messages.Value = 'Desconectado';
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [app.uploadedFile, app.uploadedPath] = uigetfile();
            if ischar(app.uploadedFile)
                app.Messages.Value= strcat('"', app.uploadedFile, ...
                    '" cargado correctamente');
                app.StartPLCButton.Enable = 'on'; %Just for testing
                app.FileSent = '1'; % Just for testing
            else
                app.StartPLCButton.Enable = "off";
            end
        end

        % Value changed function: StartPLCButton
        function StartPLCButtonValueChanged(app, event)
            value = app.StartPLCButton.Value;
            %==== Check raspberry pi connection ====%
            if strcmp(app.RPiConnection, '0')
                app.Messages.Value = 'No está conectado a la Raspberry Pi';
                app.StartPLCButton.Value = ~value;
                return
            end
            %==== Check if file has been sent ====%
            if strcmp(app.FileSent, '0')
                app.Messages.Value = 'No se ha enviado ningún archivo';
                app.StartPLCButton.Value = ~value;
                return
            end
            %==== Initialize PLC program ====%
            if value
                try
                    app.Messages.Value = 'Iniciando PLC...';
                    app.PLCRunning = writeread(app.tcpClient1, ['start', '|', '-']);
                catch
                    app.Messages.Value = 'No fue posible enviar comando para inciar PLC';
                    app.StartPLCButton.Value = ~value;
                    return
                end
                %=== PLC is running ===%
                if strcmp(app.PLCRunning, '1')
                    % Clear figure
                    app.PlotLine.YData = zeros(1, app.xlen+1);
                    app.Tank1Data = zeros(1, app.xlen+1);
                    app.Tank2Data = zeros(1, app.xlen+1);
                    app.StartPLCButton.BackgroundColor = [0.95,0.25,0.25];
                    app.StartPLCButton.Text = 'Stop PCL';
                    %app.enableControlButtons('on');
                    writeline(app.tcpClient1, 'running|-');

                    % Create modbus object for monitoring the system variables
                    app.modbusMaster = modbus('tcpip', app.raspiIP, app.modbusPort, 'Timeout', 1);
                    start(app.monitorTimer);
                    app.Messages.Value = 'PLC se ha iniciado correctamente'; 

                %=== PLC is not running ===%
                else 
                    app.StartPLCButton.Text = 'Iniciar PLC';
                    app.StartPLCButton.Value = ~value;
                    app.Messages.Value = 'No se pudo iniciar programa PLC';
                end
            %==== Stop PLC program ====%
            else
                app.Messages.Value = 'Deteniendo PLC...';
                app.stopActuators();
                configureCallback(app.tcpClient2, 'off'); % shut down serial port callback             
                app.PLCRunning = writeread(app.tcpClient1, ['stop', '|', pwd]);

                %=== PLC stopped ===%
                if strcmp(app.PLCRunning, '0')
                    app.StartPLCButton.BackgroundColor = [0.39,0.83,0.07];
                    app.StartPLCButton.Text = 'Iniciar PLC';
                    app.updateControlLEDs({[0.96,0.96,0.96], [0.96,0.96,0.96], [0.96,0.96,0.96]});
                    %app.enableControlButtons('off');
                    stop(app.monitorTimer);
                    pause(0.25);
                    app.modbusMaster = [];
                    app.Messages.Value = 'PLC se ha detenido correctamente'; 
                %=== PLC running ===%
                else
                    app.Messages.Value = 'No se pudo detener programa PLC';
                    app.StartPLCButton.Value = ~value;
                    %configureCallback(app.tcpClient2, 'byte', 1, @app.readSystemVariables);
                end
            end
        end

        % Callback function
        function KnobValueChanged(app, event)
                
        end

        % Callback function
        function EmergButtonPushed(app, event)
            %app.updateControlLEDs({[0.96,0.96,0.96], [0.96,0.96,0.96], [0.95,0.25,0.25]});
            %write(app.tcpClient2, [0, 0, 1], 'uint8');
        end

        % Callback function
        function StartButtonPushed(app, event)
            %app.updateControlLEDs({[0.39,0.83,0.07], [0.96,0.96,0.96], [0.96,0.96,0.96]});
            %write(app.tcpClient2, [1, 0, 0], 'uint8');
        end

        % Callback function
        function StopButtonPushed(app, event)
            %app.updateControlLEDs({[0.96,0.96,0.96], [1.00,0.41,0.16], [0.96,0.96,0.96]});
            %write(app.tcpClient2, [0, 1, 0], 'uint8');            
        end

        % Callback function
        function CheckConnectionButtonPushed(app, event)

        end

        % Button pushed function: SendFileButton
        function SendFileButtonPushed(app, event)
            % Check if user is connected to raspberry pi
            if strcmp(app.RPiConnection, '0')
                %app.FileLabel.Text = "No estás conectado a la RPi";
                app.Messages.Value = 'No está conectado a la Raspberry Pi';
                return
            end
            % Check if file has been sent
            if ~ischar(app.uploadedFile)
                %app.FileLabel.Text = "No se ha cargado ningún archivo";
                app.Messages.Value = 'No se ha cargado ningún archivo';
                return
            end
            % Check if PLC is running 
            if strcmp(app.PLCRunning, '1')
                %app.FileLabel.Text = 'Programa PLC está corriendo';
                app.Messages.Value = ['El programa PLC está corriendo.' ...
                    'Detenga el programa antes de enviar un nuevo archivo'];
                return
            end
            % Enviar programa PLC a la raspberry pi
            try
                %app.FileLabel.Text = 'Enviando programa PLC...';
                app.Messages.Values = 'Enviando programa PLC...';
                app.FileSent = writeread(app.tcpClient1, [fullfile(app.uploadedPath, app.uploadedFile), '|', app.uploadedFile]);
                %app.PLCLabel.Text = '';
                %app.FileLabel.Text = '';
                if strcmp(app.FileSent, '1')
                    app.StartPLCButton.Enable = "on";
                end
            catch
                %app.FileLabel.Text = "Ocurrió un error. Verifica el nombre del archivo.";
                %app.SystemInfo.Text = me.message;
                app.Messages.Value = 'Ocurrió un error. Verifica el nombre del archivo';
                return
            end
        end

        % Callback function
        function VariableDropDownValueChanged(app, event)
            app.selectedVar = app.VariableDropDown.Value;
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            try
            data = readmatrix(fullfile(pwd, 'results.xlsx'));
            [file, path] = uiputfile('download.xlsx');
            resultFilePath = fullfile(path, file);
            writematrix(data, resultFilePath);
            catch me
                app.SystemInfo.Text = me.message;
            end
        end

        % Value changed function: EmergSwitch, StartSwitch, StopSwitch
        function ControlSwitchValuesChanged(app, event)
            startSwitchValue = str2double(app.StartSwitch.Value);
            stopSwitchValue = str2double(app.StopSwitch.Value);
            emergSwitchValue = str2double(app.EmergSwitch.Value);
            display(class(startSwitchValue))
            write(app.modbusMaster, 'holdingregs', 1025, ...
                [startSwitchValue, stopSwitchValue, emergSwitchValue], ...
                'uint16');

        end

        % Value changed function: BMotorSwitch, DMotorSwitch, 
        % ...and 9 other components
        function ActuatorsSwitchValueChanged(app, event)
            %sv1SwitchValue = str2double(app.Servo1Switch.Value)*65535;
            %sv2SwitchValue = str2double(app.Servo2Switch.Value)*65535;
            %sv3SwitchValue = str2double(app.Servo3Switch.Value)*65535;
            sv1KnobValue = round(str2double(app.sv1Knob.Value)*65535/90);
            sv2KnobValue = round(str2double(app.sv2Knob.Value)*65535/90);
            sv3KnobValue = round(str2double(app.sv3Knob.Value)*65535/90);

            ev1SwitchValue = str2double(app.Electro1Switch.Value);
            ev2SwitchValue = str2double(app.Electro2Switch.Value);

            mtbSwitchValue = str2double(app.MTBSwitch.Value);

            ret1SwitchValue = str2double(app.Ret1Switch.Value)*65535;
            ret2SwitchValue = str2double(app.Ret2Switch.Value)*65535;
            ret3SwitchValue = str2double(app.Ret3Switch.Value)*65535;

            dskSwitchValue = str2double(app.DMotorSwitch.Value);
            bndSwitchValue = str2double(app.BMotorSwitch.Value);

            dskKnobValue = str2double(app.dskKnob.Value)*65535/100;
            bndKnobValue = str2double(app.bndKnob.Value)*65535/100;

            write(app.modbusMaster, 'holdingregs', 1028, ...
                [sv1KnobValue, sv2KnobValue, sv3KnobValue, ...
                ev1SwitchValue, ev2SwitchValue, ...
                mtbSwitchValue, ...
                ret1SwitchValue, ret2SwitchValue, ret3SwitchValue, ...
                dskSwitchValue, dskKnobValue, ...
                bndSwitchValue, bndKnobValue], ...
                'uint16');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Nivel de líquido en los tanques')
            xlabel(app.UIAxes, 'Muestras')
            ylabel(app.UIAxes, 'Altura (cm)')
            zlabel(app.UIAxes, 'Z')
            app.ActuadorescontroldenivelPanel = uipanel(app.UIFigure);
            app.ActuadorescontroldenivelPanel.Title = 'Actuadores control de nivel';
            app.GridLayout2 = uigridlayout(app.ActuadorescontroldenivelPanel);
            app.Electrovlvula1LampLabel = uilabel(app.GridLayout2);
            app.Electrovlvula1LampLabel.Text = 'Electroválvula 1';
            app.EV1 = uilamp(app.GridLayout2);
            app.Electrovlvula2LampLabel = uilabel(app.GridLayout2);
            app.Electrovlvula2LampLabel.Text = 'Electroválvula 2';
            app.EV2 = uilamp(app.GridLayout2);
            app.MotobombaLabel = uilabel(app.GridLayout2);
            app.MotobombaLabel.Text = 'Motobomba';
            app.MTB = uilamp(app.GridLayout2);
            app.Servovlvula3LampLabel = uilabel(app.GridLayout2);
            app.Servovlvula3LampLabel.Text = 'Servoválvula 3';
            app.SV3 = uilamp(app.GridLayout2);
            app.Servovlvula2Label = uilabel(app.GridLayout2);
            app.Servovlvula2Label.Text = 'Servoválvula 2';
            app.SV2 = uilamp(app.GridLayout2);
            app.Servovlvula1Label = uilabel(app.GridLayout2);
            app.Servovlvula1Label.Text = 'Servoválvula 1';
            app.SV1 = uilamp(app.GridLayout2);
            app.SensoresPanel = uipanel(app.UIFigure);
            app.SensoresPanel.Title = 'Sensores';
            app.GridLayout4 = uigridlayout(app.SensoresPanel);
            app.Tanque1cmLabel = uilabel(app.GridLayout4);
            app.Tanque1cmLabel.Text = 'Tanque 1 (cm)';
            app.US1 = uieditfield(app.GridLayout4, 'numeric');
            app.US1.Editable = 'off';
            app.Tanque2cmLabel = uilabel(app.GridLayout4);
            app.Tanque2cmLabel.Text = 'Tanque 2 (cm)';
            app.US2 = uieditfield(app.GridLayout4, 'numeric');
            app.US2.Editable = 'off';
            app.Retenedor3Label_2 = uilabel(app.GridLayout4);
            app.Retenedor3Label_2.Text = 'Infrarrojo 1';
            app.IR1 = uilamp(app.GridLayout4);
            app.Retenedor3Label_3 = uilabel(app.GridLayout4);
            app.Retenedor3Label_3.Text = 'Infrarrojo 2';
            app.IR2 = uilamp(app.GridLayout4);
            app.Retenedor3Label_4 = uilabel(app.GridLayout4);
            app.Retenedor3Label_4.Text = 'Infrarrojo 3';
            app.IR3 = uilamp(app.GridLayout4);
            app.Tank2Level = uigauge(app.GridLayout4, 'linear');
            app.Tank2Level.Limits = [0 30];
            app.Tank1Level = uigauge(app.GridLayout4, 'linear');
            app.Tank1Level.Limits = [0 30];
            app.ActuadoresbandadeprocesoPanel = uipanel(app.UIFigure);
            app.ActuadoresbandadeprocesoPanel.Title = 'Actuadores banda de proceso';
            app.GridLayout3 = uigridlayout(app.ActuadoresbandadeprocesoPanel);
            app.Retenedor3Label = uilabel(app.GridLayout3);
            app.Retenedor3Label.Text = 'Retenedor 3';
            app.MotordiscoLabel = uilabel(app.GridLayout3);
            app.MotordiscoLabel.Text = 'Motor disco';
            app.DSK = uilamp(app.GridLayout3);
            app.MotorbandaLabel = uilabel(app.GridLayout3);
            app.MotorbandaLabel.Text = 'Motor banda';
            app.BND = uilamp(app.GridLayout3);
            app.RET3 = uilamp(app.GridLayout3);
            app.Retenedor2Label = uilabel(app.GridLayout3);
            app.Retenedor2Label.Text = 'Retenedor 2';
            app.RET2 = uilamp(app.GridLayout3);
            app.Retenedor1Label = uilabel(app.GridLayout3);
            app.Retenedor1Label.Text = 'Retenedor 1';
            app.RET1 = uilamp(app.GridLayout3);
            app.Paneldeconexin = uipanel(app.UIFigure);
            app.Paneldeconexin.Title = 'Panel de conexión';
            app.GridLayout5 = uigridlayout(app.Paneldeconexin);
            app.ConnectRPi = uibutton(app.GridLayout5, 'state');
            app.ConnectRPi.ValueChangedFcn = createCallbackFcn(app, @ConnectRPiValueChanged, true);
            app.ConnectRPi.Enable = 'off';
            app.ConnectRPi.Text = 'Conectar RPi';
            app.StartPLCButton = uibutton(app.GridLayout5, 'state');
            app.StartPLCButton.ValueChangedFcn = createCallbackFcn(app, @StartPLCButtonValueChanged, true);
            app.StartPLCButton.Enable = 'off';
            app.StartPLCButton.Text = 'Iniciar PLC';
            app.UploadButton = uibutton(app.GridLayout5, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload.png');
            app.UploadButton.Text = '';
            app.SendFileButton = uibutton(app.GridLayout5, 'push');
            app.SendFileButton.ButtonPushedFcn = createCallbackFcn(app, @SendFileButtonPushed, true);
            app.SendFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'send_file.png');
            app.SendFileButton.Text = '';
            app.DownloadButton = uibutton(app.GridLayout5, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Icon = fullfile(pathToMLAPP, 'icons', 'excel_download.png');
            app.DownloadButton.Text = '';
            app.MensajesTextAreaLabel = uilabel(app.UIFigure);
            app.MensajesTextAreaLabel.Text = 'Mensajes';
            app.Messages = uitextarea(app.UIFigure);
            app.Messages.Editable = 'off';
            app.Paneldecontrol_2 = uipanel(app.UIFigure);
            app.Paneldecontrol_2.Title = 'Panel de control';
            app.GridLayout_2 = uigridlayout(app.Paneldecontrol_2);
            app.INICIARSwitchLabel = uilabel(app.GridLayout_2);
            app.INICIARSwitchLabel.Text = 'INICIAR';
            app.StartSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.StartSwitch.Items = {'0', '1'};
            app.StartSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValuesChanged, true);
            app.StartSwitch.Value = '0';
            app.DETENERSwitchLabel = uilabel(app.GridLayout_2);
            app.DETENERSwitchLabel.Text = 'DETENER';
            app.StopSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.StopSwitch.Items = {'0', '1'};
            app.StopSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValuesChanged, true);
            app.StopSwitch.Value = '0';
            app.EMERGENCIASwitchLabel = uilabel(app.GridLayout_2);
            app.EMERGENCIASwitchLabel.Text = 'EMERGENCIA';
            app.EmergSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.EmergSwitch.Items = {'0', '1'};
            app.EmergSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValuesChanged, true);
            app.EmergSwitch.Value = '0';
            app.ELECTROVLVULA1Label = uilabel(app.GridLayout_2);
            app.ELECTROVLVULA1Label.Text = 'ELECTROVÁLVULA 1';
            app.Electro1Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Electro1Switch.Items = {'0', '1'};
            app.Electro1Switch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.Electro1Switch.Value = '0';
            app.ELECTROVLVULA2Label = uilabel(app.GridLayout_2);
            app.ELECTROVLVULA2Label.Text = 'ELECTROVÁLVULA 2';
            app.Electro2Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Electro2Switch.Items = {'0', '1'};
            app.Electro2Switch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.Electro2Switch.Value = '0';
            app.RETENEDOR1Label = uilabel(app.GridLayout_2);
            app.RETENEDOR1Label.Text = 'RETENEDOR 1';
            app.Ret1Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Ret1Switch.Items = {'0', '1'};
            app.Ret1Switch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.Ret1Switch.Value = '0';
            app.RETENEDOR2Label = uilabel(app.GridLayout_2);
            app.RETENEDOR2Label.Text = 'RETENEDOR 2';
            app.Ret2Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Ret2Switch.Items = {'0', '1'};
            app.Ret2Switch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.Ret2Switch.Value = '0';
            app.RETENEDOR3Label = uilabel(app.GridLayout_2);
            app.RETENEDOR3Label.Text = 'RETENEDOR 3';
            app.Ret3Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Ret3Switch.Items = {'0', '1'};
            app.Ret3Switch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.Ret3Switch.Value = '0';
            app.MOTOBOMBALabel = uilabel(app.GridLayout_2);
            app.MOTOBOMBALabel.Text = 'MOTOBOMBA';
            app.MTBSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.MTBSwitch.Items = {'0', '1'};
            app.MTBSwitch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.MTBSwitch.Value = '0';
            app.dskKnob = uiknob(app.GridLayout_2, 'continuous');
            app.bndKnob = uiknob(app.GridLayout_2, 'continuous');
            app.MOTORBANDASwitchLabel = uilabel(app.GridLayout_2);
            app.MOTORBANDASwitchLabel.Text = 'MOTOR BANDA';
            app.BMotorSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.BMotorSwitch.Items = {'0', '1'};
            app.BMotorSwitch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.BMotorSwitch.Value = '0';
            app.MOTORDISCOSwitchLabel = uilabel(app.GridLayout_2);
            app.MOTORDISCOSwitchLabel.Text = 'MOTOR DISCO';
            app.DMotorSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.DMotorSwitch.Items = {'0', '1'};
            app.DMotorSwitch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.DMotorSwitch.Value = '0';
            app.SV1Label = uilabel(app.GridLayout_2);
            app.SV1Label.Text = 'SERVOVÁLVULA 1';
            app.sv1Knob = uiknob(app.GridLayout_2, 'continuous');
            app.sv1Knob.Limits = [0 90];
            app.SV2Label = uilabel(app.GridLayout_2);
            app.SV2Label.Text = 'SERVOVÁLVULA 2';
            app.sv2Knob = uiknob(app.GridLayout_2, 'continuous');
            app.sv2Knob.Limits = [0 90];
            app.SV2Label_2 = uilabel(app.GridLayout_2);
            app.SV2Label_2.Text = 'SERVOVÁLVULA 3';
            app.sv3Knob = uiknob(app.GridLayout_2, 'continuous');
            app.sv3Knob.Limits = [0 90];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'MaIE.png');
            app.SERVO1SwitchLabel = uilabel(app.UIFigure);
            app.SERVO1SwitchLabel.Text = 'SERVO. 1';
            app.Servo1Switch = uiswitch(app.UIFigure, 'toggle');
            app.Servo1Switch.Items = {'0', '1'};
            app.Servo1Switch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.Servo1Switch.Value = '0';
            app.SERVO2SwitchLabel = uilabel(app.UIFigure);
            app.SERVO2SwitchLabel.Text = 'SERVO. 2';
            app.Servo2Switch = uiswitch(app.UIFigure, 'toggle');
            app.Servo2Switch.Items = {'0', '1'};
            app.Servo2Switch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.Servo2Switch.Value = '0';
            app.SERVO3SwitchLabel = uilabel(app.UIFigure);
            app.SERVO3SwitchLabel.Text = 'SERVO. 3';
            app.Servo3Switch = uiswitch(app.UIFigure, 'toggle');
            app.Servo3Switch.Items = {'0', '1'};
            app.Servo3Switch.ValueChangedFcn = createCallbackFcn(app, @ActuatorsSwitchValueChanged, true);
            app.Servo3Switch.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_tank_lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end