classdef video_streaming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
    end

    
    properties (Access = private)
        tcpServer % Description
    end
    
    methods (Access = private)
        
        function readClientData(app, src, ~)
            if src.NumBytesAvailable>=57600
                data = read(src, src.NumBytesAvailable, 'uint8');
                img = reshape(data, 120, 160, 3);
                app.Image.ImageSource = uint8(img);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tcpServer = tcpserver('0.0.0.0', 5555);
            configureCallback(app.tcpServer, 'byte', 57600, @app.readClientData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video_streaming

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end