classdef video_streaming_ip_camera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EscalaEditField       matlab.ui.control.NumericEditField
        EscalaEditFieldLabel  matlab.ui.control.Label
        FormatoDropDown       matlab.ui.control.DropDown
        FormatoDropDownLabel  matlab.ui.control.Label
        IPEditField           matlab.ui.control.EditField
        IPEditFieldLabel      matlab.ui.control.Label
        InfoLabel             matlab.ui.control.Label
        StartStreaming        matlab.ui.control.StateButton
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Cam % Description
        Timer
        user
        serialPort = 'COM5'
        serialDevice
        RPiAvailable
    end
    
    methods (Access = private)
        %% Timer
        function TimerFcn(app, ~, ~)
            scale = app.EscalaEditField.Value;
            img = imresize(snapshot(app.Cam), scale);
            if strcmp(app.FormatoDropDown.Value, 'Grayscale')
                img = rgb2gray(img);
            end
            imshow(img, 'Parent', app.UIAxes);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get user data
            try
                log = compiler.UserInfo();
                app.user = log.UserID;
            catch
                app.user = 'Unknown';
            end
            % Serial port device

            % Timer
            app.Timer = timer( ...
                'ExecutionMode', 'fixedSpacing', ...
                'Period', 0.35, ...
                'BusyMode', 'drop', ...
                'TimerFcn', @app.TimerFcn);
            stop(app.Timer);
        end

        % Value changed function: StartStreaming
        function StartStreamingValueChanged(app, event)
            value = app.StartStreaming.Value;
            if value
                try 
                    ip = app.IPEditField.Value;
                    app.Cam = ipcam(ip);
                    app.StartStreaming.BackgroundColor = [0.95,0.25,0.25];
                    app.StartStreaming.Text = 'Detener transmisión';
                    start(app.Timer);
                catch me
                    app.InfoLabel.Text = me.message;
                end
            else
                app.StartStreaming.BackgroundColor = [0.39,0.83,0.07];
                app.StartStreaming.Text = 'Iniciar transmisión';
                stop(app.Timer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.StartStreaming = uibutton(app.UIFigure, 'state');
            app.StartStreaming.ValueChangedFcn = createCallbackFcn(app, @StartStreamingValueChanged, true);
            app.StartStreaming.Text = 'Iniciar transmisión';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = '';
            app.IPEditFieldLabel = uilabel(app.UIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.UIFigure, 'text');
            app.FormatoDropDownLabel = uilabel(app.UIFigure);
            app.FormatoDropDownLabel.Text = 'Formato';
            app.FormatoDropDown = uidropdown(app.UIFigure);
            app.FormatoDropDown.Items = {'Grayscale', 'RGB'};
            app.FormatoDropDown.Value = 'Grayscale';
            app.EscalaEditFieldLabel = uilabel(app.UIFigure);
            app.EscalaEditFieldLabel.Text = 'Escala';
            app.EscalaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video_streaming_ip_camera

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end